classdef GUI_416 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AmplitudeuAcm2Label          matlab.ui.control.Label
        AmplitudeuAcm2EditField      matlab.ui.control.EditField
        DurationmsecEditFieldLabel   matlab.ui.control.Label
        DurationmsecEditField        matlab.ui.control.EditField
        MorethanonestimulusCheckBox  matlab.ui.control.CheckBox
        TimedelaymsecEditFieldLabel  matlab.ui.control.Label
        TimedelaymsecEditField       matlab.ui.control.EditField
        SimulateButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        current_amplitude; % Description
        current_duration;
        flag = false;
        time_delay;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AmplitudeuAcm2EditField
        function AmplitudeuAcm2EditFieldValueChanged(app, event)
            value = app.AmplitudeuAcm2EditField.Value;
            app.current_amplitude = value;
        end

        % Value changed function: DurationmsecEditField
        function DurationmsecEditFieldValueChanged(app, event)
            value = app.DurationmsecEditField.Value;
            app.current_duration = value;
        end

        % Value changed function: MorethanonestimulusCheckBox
        function MorethanonestimulusCheckBoxValueChanged(app, event)
            value = app.MorethanonestimulusCheckBox.Value;
            app.flag = true;
        end

        % Value changed function: TimedelaymsecEditField
        function TimedelaymsecEditFieldValueChanged(app, event)
            value = app.TimedelaymsecEditField.Value;
            app.time_delay = value;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            my_simulate(app.current_amplitude, app.current_duration, app.flag, app.time_delay);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AmplitudeuAcm2Label = uilabel(app.UIFigure);
            app.AmplitudeuAcm2Label.Text = 'Amplitude (uA/cm^2)';
            app.AmplitudeuAcm2EditField = uieditfield(app.UIFigure, 'text');
            app.AmplitudeuAcm2EditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeuAcm2EditFieldValueChanged, true);
            app.DurationmsecEditFieldLabel = uilabel(app.UIFigure);
            app.DurationmsecEditFieldLabel.Text = 'Duration (msec)';
            app.DurationmsecEditField = uieditfield(app.UIFigure, 'text');
            app.DurationmsecEditField.ValueChangedFcn = createCallbackFcn(app, @DurationmsecEditFieldValueChanged, true);
            app.MorethanonestimulusCheckBox = uicheckbox(app.UIFigure);
            app.MorethanonestimulusCheckBox.ValueChangedFcn = createCallbackFcn(app, @MorethanonestimulusCheckBoxValueChanged, true);
            app.MorethanonestimulusCheckBox.Text = 'More than one stimulus';
            app.TimedelaymsecEditFieldLabel = uilabel(app.UIFigure);
            app.TimedelaymsecEditFieldLabel.Text = 'Time delay (msec)';
            app.TimedelaymsecEditField = uieditfield(app.UIFigure, 'text');
            app.TimedelaymsecEditField.ValueChangedFcn = createCallbackFcn(app, @TimedelaymsecEditFieldValueChanged, true);
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_416

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end