classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        AngleStepsizeEditFieldLabel   matlab.ui.control.Label
        AngleStepsizeEditField        matlab.ui.control.NumericEditField
        ofSamplingEditFieldLabel      matlab.ui.control.Label
        ofSamplingEditField           matlab.ui.control.NumericEditField
        TaketheprojectionButton       matlab.ui.control.Button
        ShowprojectionButton          matlab.ui.control.Button
        ShowimageButton               matlab.ui.control.Button
        LoadImageButton               matlab.ui.control.Button
        CenterPanel                   matlab.ui.container.Panel
        RightPanel                    matlab.ui.container.Panel
        LoaddatafrommatButton         matlab.ui.control.Button
        FilteringCheckBox             matlab.ui.control.CheckBox
        LoaddatafromtxtButton         matlab.ui.control.Button
        TakethebackprojectionButton   matlab.ui.control.Button
        ShowbackprojectionButton      matlab.ui.control.Button
        SizeoftheImageEditFieldLabel  matlab.ui.control.Label
        SizeoftheImageEditField       matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        raw_data;   % Raw image
        Projection_angle_step_size;
        Number_of_sampling_point;% Description
        projection_matrix;
        back_projection_data;
        reconstructed_image_size;
        Number_of_sampling_point_for_back;
        back_projection_matrix;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            addpath("C:\Users\berkenutku\Desktop\415_HW\P3_Sample Images")
            [file,~,~] = uiputfile('*.mat','Workspace File');
            [~,name,~] = fileparts(file);   
            data_struct = load(file);
            data = data_struct.(name);
            app.raw_data = data;
        end

        % Value changed function: AngleStepsizeEditField
        function AngleStepsizeEditFieldValueChanged(app, event)
            value = app.AngleStepsizeEditField.Value;
            app.Projection_angle_step_size = value;
        end

        % Value changed function: ofSamplingEditField
        function ofSamplingEditFieldValueChanged(app, event)
            value = app.ofSamplingEditField.Value;
            app.Number_of_sampling_point = value;
        end

        % Button pushed function: ShowimageButton
        function ShowimageButtonPushed(app, event)
            imshow(app.raw_data,[],'InitialMagnification','fit');
        end

        % Button pushed function: TaketheprojectionButton
        function TaketheprojectionButtonPushed(app, event)
            M = size(app.raw_data);
            M = M(1,1);
            t_value_first = -M * sqrt(2) / 2;
            t_value_last = t_value_first * -1;
            t_values = linspace(t_value_first,t_value_last, app.Number_of_sampling_point);
            
            steps_for_theta = 0:floor(180/(app.Projection_angle_step_size));
            theta_values = app.Projection_angle_step_size .* steps_for_theta;
            
            %% Calculatin relavant intersection points
            app.projection_matrix = find_projections(theta_values,t_values,M,app.raw_data);
        end

        % Button pushed function: ShowprojectionButton
        function ShowprojectionButtonPushed(app, event)

            imshow(app.projection_matrix,[],'InitialMagnification','fit');
                
        end

        % Button pushed function: LoaddatafrommatButton
        function LoaddatafrommatButtonPushed(app, event)
            [file,~,~] = uiputfile('*.mat','Workspace File');
            [~,name,~] = fileparts(file);   
            data_struct = load(file);
            data = data_struct.(name);
            app.back_projection_data = data;
        end

        % Value changed function: FilteringCheckBox
        function FilteringCheckBoxValueChanged(app, event)
            size_of_back = size(app.back_projection_data);
            L = size_of_back(1,2);
            
            Triangle = triang(L);
            
            fft_of_projection_data = fft2(app.back_projection_data);

            result_of_multiplication = Triangle .* fft_of_projection_data.';

            filtering_output = ifft2(result_of_multiplication);
            
            app.back_projection_data = filtering_output.';
        end

        % Button pushed function: TakethebackprojectionButton
        function TakethebackprojectionButtonPushed(app, event)
            L = size(app.back_projection_data);
            rows = L(1,1);
            columns = L(1,2);
            app.Number_of_sampling_point_for_back = columns;
            app.Projection_angle_step_size = round(180/rows);
            steps_for_theta = 0:floor(180/app.Projection_angle_step_size);
            
            theta_values = app.Projection_angle_step_size .* steps_for_theta;
            
            t_value_first = -app.reconstructed_image_size * sqrt(2) / 2;
            t_value_last = t_value_first * -1;
            t_values = linspace( t_value_first,t_value_last, app.Number_of_sampling_point_for_back);
            
            app.back_projection_matrix = find_back_projections(theta_values,t_values,app.reconstructed_image_size,app.back_projection_data);
        end

        % Value changed function: SizeoftheImageEditField
        function SizeoftheImageEditFieldValueChanged(app, event)
            value = app.SizeoftheImageEditField.Value;
            app.reconstructed_image_size = value;
        end

        % Button pushed function: ShowbackprojectionButton
        function ShowbackprojectionButtonPushed(app, event)
            imshow(app.back_projection_matrix,[],'InitialMagnification','fit');
        end

        % Button pushed function: LoaddatafromtxtButton
        function LoaddatafromtxtButtonPushed(app, event)
            app.back_projection_data = read_txt_file();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {373, 373, 373};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {373, 373};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {229, '1x', 347};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AngleStepsizeEditFieldLabel = uilabel(app.LeftPanel);
            app.AngleStepsizeEditFieldLabel.Text = 'Angle Step size';
            app.AngleStepsizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AngleStepsizeEditField.ValueChangedFcn = createCallbackFcn(app, @AngleStepsizeEditFieldValueChanged, true);
            app.ofSamplingEditFieldLabel = uilabel(app.LeftPanel);
            app.ofSamplingEditFieldLabel.Text = '# of Sampling';
            app.ofSamplingEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ofSamplingEditField.ValueChangedFcn = createCallbackFcn(app, @ofSamplingEditFieldValueChanged, true);
            app.TaketheprojectionButton = uibutton(app.LeftPanel, 'push');
            app.TaketheprojectionButton.ButtonPushedFcn = createCallbackFcn(app, @TaketheprojectionButtonPushed, true);
            app.TaketheprojectionButton.Text = 'Take the projection';
            app.ShowprojectionButton = uibutton(app.LeftPanel, 'push');
            app.ShowprojectionButton.ButtonPushedFcn = createCallbackFcn(app, @ShowprojectionButtonPushed, true);
            app.ShowprojectionButton.Text = 'Show  projection';
            app.ShowimageButton = uibutton(app.LeftPanel, 'push');
            app.ShowimageButton.ButtonPushedFcn = createCallbackFcn(app, @ShowimageButtonPushed, true);
            app.ShowimageButton.Text = 'Show image';
            app.LoadImageButton = uibutton(app.LeftPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.LoaddatafrommatButton = uibutton(app.RightPanel, 'push');
            app.LoaddatafrommatButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddatafrommatButtonPushed, true);
            app.LoaddatafrommatButton.Text = 'Load data from .mat';
            app.FilteringCheckBox = uicheckbox(app.RightPanel);
            app.FilteringCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilteringCheckBoxValueChanged, true);
            app.FilteringCheckBox.Text = 'Filtering';
            app.LoaddatafromtxtButton = uibutton(app.RightPanel, 'push');
            app.LoaddatafromtxtButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddatafromtxtButtonPushed, true);
            app.LoaddatafromtxtButton.Text = 'Load data from .txt';
            app.TakethebackprojectionButton = uibutton(app.RightPanel, 'push');
            app.TakethebackprojectionButton.ButtonPushedFcn = createCallbackFcn(app, @TakethebackprojectionButtonPushed, true);
            app.TakethebackprojectionButton.Text = 'Take the back projection';
            app.ShowbackprojectionButton = uibutton(app.RightPanel, 'push');
            app.ShowbackprojectionButton.ButtonPushedFcn = createCallbackFcn(app, @ShowbackprojectionButtonPushed, true);
            app.ShowbackprojectionButton.Text = 'Show back projection';
            app.SizeoftheImageEditFieldLabel = uilabel(app.RightPanel);
            app.SizeoftheImageEditFieldLabel.Text = 'Size of the Image';
            app.SizeoftheImageEditField = uieditfield(app.RightPanel, 'numeric');
            app.SizeoftheImageEditField.ValueChangedFcn = createCallbackFcn(app, @SizeoftheImageEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end